classdef circ_clustering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        LoadFileMenu  matlab.ui.container.Menu
        UIAxes        matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Menu selected function: LoadFileMenu
        function LoadFileMenuSelected(app, event)
            [filename filepath] = uigetfile('.csv', 'MultiSelect','on');
            
            tempvarname = filename;
            tempvarpath = filepath;
            allx = [];
            ally = [];
            
            switch(class(filename))
                case 'double'
                    disp('Selection aborted')
                case 'char'
                    newfile = strcat(tempvarpath, tempvarname); %filepath for particular file
                        
                        x =  tempvarname;
                        
                        delimiter = ',';
                        startRow = 10;
                        formatSpec = '%*s%*s%f%*s%s%[^\n\r]';
        
                        fileID = fopen(newfile,'r');
                        dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines' ,startRow-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                        fclose(fileID);
                        
                        datavec = table(dataArray{1:end-1}, 'VariableNames', {'Minutes','Event'});
                        clearvars filename delimiter startRow formatSpec fileID dataArray ans;
                        
                        min = datavec{1:height(datavec),{'Minutes'}};
                        event = datavec{1:height(datavec),{'Event'}};
                         
                        for i= 1:length(event)
                            if event(i) == "Seizure"
                                 zero_m(i) = min(i);
                            end;
                        end;
                        
                        final_m = zero_m(zero_m ~= 0);
                        
                        temp = {x(7:10),x(11:12),x(13:14),x(16:17),x(18:19),x(20:21)};
                        for i = 1:length(temp)
                            final(i) = str2num(temp{i});
                        end
                        
                        
                        if x(1:5) == 'fKH14'
                            t0 = datetime(2017,6,9,14,24,00);
                        end;
                        if x(1:5) == 'fKH16'
                            t0 = datetime(2017,6,9,15,49,00);
                        end;
                        if x(1:5) == 'fKH12'
                            t0 = datetime(2017,3,4,15,49,00);
                        end;
                        if x(1:5) == 'fKH33'
                            t0 = datetime(2018,2,19,17,05,00);
                        end;
                        if x(1:5) == 'fKH50'
                            t0 = datetime(2018,3,31,17,48,00);
                        end
                        
                        
                        %t0 = datetime(final);
                        %t0 = datetime(2017,3,4,14,24,00); %injection day
                        t2 = datetime(final);
                        t1 = t2 + minutes(final_m(:));
                        
                        y = days(t1-t0);
                        hourdec = hour(t1) + minute(t1)/60;
                        
                        %patch(app.UIAxes,[y(1) y(end) y(end) y(1)],[6 6 18 18],[0.9290,0.6940,0.1250])
                        scatter(app.UIAxes, y, hourdec, 'filled', 'b');
                        title(app.UIAxes,x(1:5))
                        
                        %set(gca,'children',flipud(get(gca,'children')))
                        yticks([0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24])
                        hold(app.UIAxes, 'on');
                        
                        numberOfSeizures = length(hourdec)
                case 'cell'
                    for j = 1:length(filename)
                        newfile = strcat(tempvarpath, tempvarname{j}); %filepath for particular file
                        
                        x =  tempvarname{j};
                        
                        delimiter = ',';
                        startRow = 10;
                        formatSpec = '%*s%*s%f%*s%s%[^\n\r]';
        
                        fileID = fopen(newfile,'r');
                        dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines' ,startRow-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                        fclose(fileID);
                        
                        datavec = table(dataArray{1:end-1}, 'VariableNames', {'Minutes','Event'});
                        clearvars filename delimiter startRow formatSpec fileID dataArray ans;
                        
                        min = datavec{1:height(datavec),{'Minutes'}};
                        event = datavec{1:height(datavec),{'Event'}};
                         
                        for i= 1:length(event)
                            if event(i) == "Seizure"
                                 zero_m(i) = min(i);
                            end
                        end
                        
                        final_m = zero_m(zero_m ~= 0);
                        
                        temp = {x(7:10),x(11:12),x(13:14),x(16:17),x(18:19),x(20:21)};
                        for i = 1:length(temp)
                            final(i) = str2num(temp{i});
                        end
                        
                        
                        if x(1:5) == 'fKH14'
                            t0 = datetime(2017,6,9,14,24,00);
                        end
                        if x(1:5) == 'fKH16'
                            t0 = datetime(2017,6,9,15,49,00);
                        end
                        if x(1:5) == 'fKH12'
                            t0 = datetime(2017,3,4,15,49,00);
                        end
                        if x(1:5) == 'fKH33'
                            t0 = datetime(2018,2,19,17,05,00);
                        end
                        if x(1:5) == 'fKH50'
                            t0 = datetime(2018,3,31,17,48,00);
                        end
                        
                        %t0 = datetime(final);
                        %t0 = datetime(2017,3,4,14,24,00); %injection day
                        t2 = datetime(final);
                        t1 = t2 + minutes(final_m(:));
                        
                        y = days(t1-t0);
                        hourdec = hour(t1) + minute(t1)/60;
                        
                        %patch(app.UIAxes,[y(1) y(end) y(end) y(1)],[6 6 18 18],[0.9290,0.6940,0.1250])
                        scatter(app.UIAxes, y, hourdec, 'filled', 'b');
                        %title(app.UIAxes,x(1:5))
                        
                        %set(gca,'children',flipud(get(gca,'children')))
                        yticks([0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24])
                        hold(app.UIAxes, 'on');
                        
                        numberOfSeizures = length(hourdec)
                        
                        allx = [allx; y];
                        ally = [ally; hourdec];
                                        
                    end
                    
%                     opts = statset('Display','iter');
%                     %[idx,C,sumd,d,midx,info] = kmedoids(hourdec,2,'Distance','cityblock','Options',opts);
%                     stacked = [allx ally];
%                     [idx,C,sumd,d,midx,info] = kmedoids(stacked,4,'Distance','cityblock','Options',opts);
%                     
%                     cluster1 = ally(idx == 1);
%                     x1 = allx(idx==1);
%                     cluster2 = ally(idx == 2);
%                     x2 = allx(idx==2);
%                     cluster3 = ally(idx == 3);
%                     x3 = allx(idx==3);
%                     cluster4 = ally(idx == 4);
%                     x4 = allx(idx==4);
%                     
%                     % How to convert for loop into logical indexing
%                     
%                     % for i= 1:length(hourdec)
%                     %     if idx(i) == 1
%                     %          cluster1 = [cluster1 ; hourdec(i)];
%                     %          x1 = [x1 ; y(i)];
%                     %     else
%                     %         cluster2 = [cluster2 ; hourdec(i)];
%                     %         x2 = [x2 ; y(i)];
%                     %     end;
%                     % end;
%                     
%                     figure;
%                     scatter(x1, cluster1, 'r', 'filled');
%                     hold on
%                     scatter(x2, cluster2, 'b', 'filled');
%                     hold on
%                     scatter(x3, cluster3, 'g', 'filled');
%                     hold on
%                     scatter(x4, cluster4, 'black', 'filled');
%                     hold on
%                     ylabel('Time of Day (HH)')
%                     xlabel('Time since start (DD)')
%                     yticks([0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24])
%                     hold on
%                     plot(C(:,1),C(:,2),'x', 'MarkerSize', 15);
%                     %legend('Cluster 1','Cluster 2', 'Medoids' ,'Location','NW');
%                     hold off
                    
                otherwise
                    disp('Unexpected output')
                end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadFileMenu = uimenu(app.UIFigure);
            app.LoadFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFileMenuSelected, true);
            app.LoadFileMenu.Text = 'Load File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Days since Injection (DD)')
            ylabel(app.UIAxes, 'Time of Day (HH)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = circ_clustering

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end